classdef cmc_isometric < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure          matlab.ui.Figure
		ComputeCMCButton  matlab.ui.control.Button
		FileNameLabel     matlab.ui.control.Label
		EEGfileLabel      matlab.ui.control.Label
		SelectButton      matlab.ui.control.Button
		UIAxes_tricep     matlab.ui.control.UIAxes
		UIAxes_bicep      matlab.ui.control.UIAxes
		UIAxes_C4         matlab.ui.control.UIAxes
		UIAxes_C3         matlab.ui.control.UIAxes
	end

	
	properties (Access = public)
		time     % time vector of downsampled data
		c3_car   % c3 data common avg ref (downsampled and filtered)
		c4_car   % c4 data common avg ref
		bicep_filt  % bicep emg data
		tricep_filt  % tricep emg data
		samp_rate	 % sampling rate (data is downsampled to)
		seg_pwr		 % num of datapoints for analysis as a power of 2
		c3_bicep	 % neurospec results for c3-bicep coherence
		c4_bicep     %                       c4-bicep
		c3_tricep    %                       c3-tricep
		c4_tricep    %                       c4-tricep
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			linkaxes([app.UIAxes_C3 app.UIAxes_C4 app.UIAxes_bicep app.UIAxes_tricep], 'x')
			app.samp_rate = 1000;	% sampling rate (data will be downsampled to this)
			app.seg_pwr = 10; % 2^10 = 1024
		end

		% Button pushed function: SelectButton
		function SelectButtonPushed(app, event)
			% request eeg file to process
			disp('Choose a BrainVision .vhdr file')
			[fname, pname] = uigetfile('*.vhdr', 'Choose a BrainVision .vhdr file');
			if isequal(fname, 0) || isequal(pname,0)
				disp('User pressed cancel')
				return
			else
				filename = fullfile(pname, fname);
				app.FileNameLabel.Text = filename;
			end
			h_dlg = uiprogressdlg(app.UIFigure, 'Title', 'Reading in Data', 'Indeterminate','on');

			% read in the data
			[pname, fname, ext] = fileparts(filename);
			[data, ~] = pop_loadbv(pname, [fname ext]);
			n_chans = 31;
			% find the chan numbers for C3, C4, and Cz, bicep, and tricep
			c3_chan_num = []; c4_chan_num = []; cz_chan_num = []; bicep_chan_num = []; tricep_chan_num = [];
			for ch_cnt = 1:length(data.chanlocs)
				switch lower(data.chanlocs(ch_cnt).labels)
					case 'c3'
						c3_chan_num = ch_cnt;
					case 'c4'
						c4_chan_num = ch_cnt;
					case 'cz'
						cz_chan_num = ch_cnt;
					case 'bicep'
						bicep_chan_num = ch_cnt;
					case 'tricep'
						tricep_chan_num = ch_cnt;
				end
			end
			if isempty(c3_chan_num)
				keyboard
			end
			if isempty(c4_chan_num)
				keyboard
			end
			if isempty(cz_chan_num)
				keyboard
			end
			if isempty(bicep_chan_num)
				keyboard
			end
			if isempty(tricep_chan_num)
				keyboard
			end
			
			% downsample to 1kHz
			h_dlg.Title = 'Down sampling the Data';
			
			data.srate_down = app.samp_rate;
			factor = data.srate / data.srate_down;
			if abs(factor-fix(factor)) > eps
				fprintf('cmc_isometric.m sampling rate, %g, cannot be downsampled to 1kHz\n', data.srate);
				return
			end

			fprintf('down sampling by a factor of %d\n', factor)
			data.downsamp = zeros(size(data.data,1), data.pnts/factor);
			for c_cnt = [1:n_chans, bicep_chan_num, tricep_chan_num]
				data.downsamp(c_cnt, :) = decimate(double(data.data(c_cnt,:)), factor);
			end

			app.time = (1:length(data.downsamp)) / data.srate_down;

			h_dlg.Title = 'Filtering the Data';
			% filter values
			Setup.EMGcutOffLowHz=5;
			Setup.EMGcutOffHighHz=499;
			Setup.EEGcutOffLowHz=0.5;
			Setup.EEGcutOffHighHz=100;

% 			low = Setup.EMGcutOffLowHz / (data.srate/2);
% 			high = Setup.EMGcutOffHighHz / (data.srate/2);
% 			[B_emg, A_emg] = butter(5, [low, high], 'bandpass' );
% 
% 			low = Setup.EEGcutOffLowHz / (data.srate/2);
% 			high = Setup.EEGcutOffHighHz / (data.srate/2);
% 			[B_eeg, A_eeg] = butter(4, [low, high], 'bandpass' );

			% filter the data

			eeg_hp = zeros(n_chans, data.pnts/factor);
			eeg_filt = zeros(n_chans, data.pnts/factor);
			for c_cnt = 1:n_chans
				eeg_hp(c_cnt, :) = hpfilt(data.downsamp(c_cnt,:)', 4, Setup.EEGcutOffLowHz, data.srate_down)';
				eeg_filt(c_cnt, :) = lpfilt(eeg_hp(c_cnt, :), 4, Setup.EEGcutOffHighHz, data.srate_down)';
			end

			c3_hp = hpfilt(data.downsamp(c3_chan_num,:)', 4, Setup.EEGcutOffLowHz, data.srate_down);
			c3_filt = lpfilt(c3_hp, 4, Setup.EEGcutOffHighHz, data.srate_down);

			c4_hp = hpfilt(data.downsamp(c4_chan_num,:)', 4, Setup.EEGcutOffLowHz, data.srate_down);
			c4_filt = lpfilt(c4_hp, 4, Setup.EEGcutOffHighHz, data.srate_down);

			cz_hp = hpfilt(data.downsamp(cz_chan_num,:)', 4, Setup.EEGcutOffLowHz, data.srate_down);
			cz_filt = lpfilt(cz_hp, 4, Setup.EEGcutOffHighHz, data.srate_down);

			% common average reference
			F_all = ones(n_chans)*(-1/(n_chans-1)) + eye(n_chans)*(1+1/(n_chans-1)); %CAR matrix
			app.c3_car = F_all(c3_chan_num, :) * eeg_filt;
			app.c4_car = F_all(c4_chan_num, :) * eeg_filt;

% 			c3_cz = c3_filt - cz_filt;
% 			c4_cz = c4_filt - cz_filt;

			bicep_hp = hpfilt(data.downsamp(bicep_chan_num,:)', 4, Setup.EMGcutOffLowHz, data.srate_down);
			app.bicep_filt = lpfilt(bicep_hp, 4, Setup.EMGcutOffHighHz, data.srate_down);

			tricep_hp = hpfilt(data.downsamp(tricep_chan_num,:)', 4, Setup.EMGcutOffLowHz, data.srate_down);
			app.tricep_filt = lpfilt(tricep_hp, 4, Setup.EMGcutOffHighHz, data.srate_down);

			% display the data
			h_dlg.Title = 'Drawing the Data';
			if ~isempty(app.UIAxes_C3.Children)
				delete(app.UIAxes_C3.Children)
			end
			line(app.UIAxes_C3, app.time, app.c3_car)
			if ~isempty(app.UIAxes_C4.Children)
				delete(app.UIAxes_C4.Children)
			end
			line(app.UIAxes_C4, app.time, app.c4_car)
			if ~isempty(app.UIAxes_bicep.Children)
				delete(app.UIAxes_bicep.Children)
			end
			line(app.UIAxes_bicep, app.time, app.bicep_filt)
			if ~isempty(app.UIAxes_tricep.Children)
				delete(app.UIAxes_tricep.Children)
			end
			line(app.UIAxes_tricep, app.time, app.tricep_filt)
			app.UIAxes_C3.XLim = [0 max(app.time)];

			% add event times to emg and patches to all axes
			for e_cnt = 1:length(data.event)
				if strcmpi(data.event(e_cnt).code, 'rest')
					t = data.event(e_cnt).latency / data.srate;
					line(app.UIAxes_bicep, [t t], app.UIAxes_bicep.YLim, 'Color', [0.9 0 0])
					line(app.UIAxes_tricep, [t t], app.UIAxes_tricep.YLim, 'Color', [0.9 0 0])
				elseif strcmpi(data.event(e_cnt).code, 'extend')
					t = data.event(e_cnt).latency / data.srate;
					line(app.UIAxes_bicep, [t t], app.UIAxes_bicep.YLim, 'Color', [0 0.9 0])
					line(app.UIAxes_tricep, [t t], app.UIAxes_tricep.YLim, 'Color', [0 0.9 0])
					add_cmc_patch(app, t+1, t+5)
				end

			end

			close(h_dlg)
		end

		% Button pushed function: ComputeCMCButton
		function ComputeCMCButtonPushed(app, event)
			% get the time intervals to analyze
			patch_list = findobj(app.UIAxes_tricep, 'type', 'Patch');
			trig_times = zeros(length(patch_list), 1);
			duration = zeros(length(patch_list), 1);

			for p_cnt = 1:length(patch_list)
				xdata = patch_list(p_cnt).XData;
				t_start = xdata(1);
				t_end = xdata(3);
				ind_start = find(app.time >= t_start, 1);
				ind_end = find(app.time >= t_end, 1);

				trig_times(p_cnt) = ind_start;
				duration(p_cnt) = ind_end - ind_start + 1;
			end

			% call the neurospec function
			[app.c3_bicep.f, app.c3_bicep.t, app.c3_bicep.cl, app.c3_bicep.sc] = ...
				sp2a2_m1(1, app.c3_car', app.bicep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			[app.c4_bicep.f, app.c4_bicep.t, app.c4_bicep.cl, app.c4_bicep.sc] = ...
				sp2a2_m1(1, app.c4_car', app.bicep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			[app.c3_tricep.f, app.c3_tricep.t, app.c3_tricep.cl, app.c3_tricep.sc] = ...
				sp2a2_m1(1, app.c3_car', app.tricep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			[app.c4_tricep.f, app.c4_tricep.t, app.c4_tricep.cl, app.c4_tricep.sc] = ...
				sp2a2_m1(1, app.c4_car', app.tricep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			% Output parameters
%  f column 1       frequency in Hz.
%  f column 2       Log input/dat1  spectrum.
%  f column 3       Log output/dat2 spectrum.
%  f column 4       Coherence.
%  f column 5       Phase.
%  f column 6       Log of gain magnitude (with s option).
%  f column 6 or 7  Periodogram COV test  input channel (with c option).
%  f column 7 or 8  Periodogram COV test output channel (with c option).
%
%  t column 1       Lag in ms.
%  t column 2       Cumulant density.
%  t column 3       Impulse response (with s option).
%
%  cl.type          Analysis type (0, 1, 2)
%  cl.seg_size      Segment length.
%  cl.seg_tot       Number of segments.
%  cl.seg_tot_var   Effective no of segments, used to calculate confidence limits.
%  cl.samp_tot      Number of samples analysed.
%  cl.samp_rate     Sampling rate of data (samps/sec).
%  cl.dt            Time domain bin width (ms).
%  cl.df            Frequency domain bin width (Hz).
%  cl.f_c95         95% confidence limit for Log spectral estimates.
%  cl.ch_c95        95% confidence limit for coherence.
%  cl.q_c95         95% confidence limit for cumulant density.
%  cl.a_c95         95% confidence limit for impulse response      (with s option).
%  cl.col_g         column in f matrix containing gain estimate    (with s option).
%  cl.col_a         column in t matrix containing impulse response (with s option).
%  col_cova         Column containing Periodogram COV test on  input channel (with c option).
%  col_covb         Column containing Periodogram COV test on output channel (with c option).
%  cl.seg_pts       Data points in segment       (Type 2 only).
%  cl.offset        Offset value(s) for analysis (Type 2 only).
%  cl.opt_str       Copy of options string.
%  cl.what          Text label, used in plotting routines.
%
%  sc column 1      f11.
%  sc column 2      f22.
%  sc column 3      f21 (Complex).


			% display output
			freq = app.samp_rate/2;
			lag_tot = freq;
			lag_neg = freq/2;
			ch_max = 2 * max(app.c3_bicep.f(:,4));
			label = 'C3-bicep';
			figure
			psp2(app.c3_bicep.f, app.c3_bicep.t, app.c3_bicep.cl, freq, lag_tot, lag_neg, ch_max, label)
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIAxes_C3 = uiaxes(app.UIFigure);
			title(app.UIAxes_C3, 'EEG')
			xlabel(app.UIAxes_C3, 'X')
			ylabel(app.UIAxes_C3, 'Y')
			zlabel(app.UIAxes_C3, 'Z')
			app.UIAxes_C4 = uiaxes(app.UIFigure);
			xlabel(app.UIAxes_C4, 'X')
			ylabel(app.UIAxes_C4, 'Y')
			zlabel(app.UIAxes_C4, 'Z')
			app.UIAxes_bicep = uiaxes(app.UIFigure);
			title(app.UIAxes_bicep, 'EMG')
			xlabel(app.UIAxes_bicep, 'X')
			ylabel(app.UIAxes_bicep, 'Y')
			zlabel(app.UIAxes_bicep, 'Z')
			app.UIAxes_tricep = uiaxes(app.UIFigure);
			xlabel(app.UIAxes_tricep, 'X')
			ylabel(app.UIAxes_tricep, 'Y')
			zlabel(app.UIAxes_tricep, 'Z')
			app.SelectButton = uibutton(app.UIFigure, 'push');
			app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
			app.SelectButton.Text = 'Select';
			app.EEGfileLabel = uilabel(app.UIFigure);
			app.EEGfileLabel.Text = 'EEG file:';
			app.FileNameLabel = uilabel(app.UIFigure);
			app.ComputeCMCButton = uibutton(app.UIFigure, 'push');
			app.ComputeCMCButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeCMCButtonPushed, true);
			app.ComputeCMCButton.Text = 'Compute CMC';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = cmc_isometric

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.UIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.UIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end