classdef cocontraction < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CoConUIFigure               matlab.ui.Figure
		AnalysisdateEditField       matlab.ui.control.EditField
		AnalysisdateEditFieldLabel  matlab.ui.control.Label
		AnalysisbyEditField         matlab.ui.control.EditField
		AnalysisbyEditFieldLabel    matlab.ui.control.Label
		cocontractionmlappversion0120220928Label  matlab.ui.control.Label
		DataFileLabel               matlab.ui.control.Label
		EditField_2                 matlab.ui.control.NumericEditField
		EditField                   matlab.ui.control.NumericEditField
		FileNameLabel               matlab.ui.control.Label
		SelectButton                matlab.ui.control.Button
		UIAxes_cci                  matlab.ui.control.UIAxes
		UIAxes_tricep               matlab.ui.control.UIAxes
		UIAxes_bicep                matlab.ui.control.UIAxes
	end


	properties (Access = public)
		emg_data      % emg data read in from brainvision file
		mvcs			% struct with mvc values
		cci_data        % co-contraction - continuos data computed from eeg_data and mvcs
		links			% struct to hold link property handles
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% link the XLim of the axes
			app.links.axes_xlim = linkprop([app.UIAxes_bicep, app.UIAxes_tricep, app.UIAxes_cci], 'XLim');
		end

		% Button pushed function: SelectButton
		function SelectButtonPushed(app, event)
			[filename, pathname] = uigetfile('*.vhdr', 'Choose vhdr file with MVC data');
			if ~isequal(filename,0) &&  ~isequal(pathname,0)
				app.FileNameLabel.Text = fullfile(pathname, filename);
				% read in the data
				if contains(filename, '.vhdr')
					% read in data from brain vision file
					app.emg_data = pop_loadbv(pathname, filename, [], [64 65]);
					% data structure:
					%   comments: 'Original file: 20120517_inv_wrist_flexors_active_rc_epochs_450ms.seg' (or .eeg)
					%       srate: 5000				sampling rate in Hz
					%      nbchan: 8				number of channels recorded
					%    chanlocs: [1x8 struct]		array of the channel names (strings)
					%        pnts: 2250				number of data points in a trial
					%        data: [8x90000 single]	data matrix (1 row for each data collection channel)
					%      trials: 40				number of trials
					%        xmin: -0.0500
					%        xmax: 17.9998
					%       event: [1x80 struct]	struct array with fields:
					%									latency			starting data point number for the event
					%									type			'R128' events are the start of trials we want
					%									code
					%									duration
					%									urevent
					%									epoch
					%     urevent: [1x120 struct]
					%         ref: 'common'

					% verify that the 2 data channels are bicep and tricep
					assert(contains(app.emg_data.chanlocs(1).labels, 'bicep'), 'bicep channel not read in')
					assert(contains(app.emg_data.chanlocs(2).labels, 'tricep'), 'tricep channel not read in')

					% add time vector to emg_data. Time of first sample is 1/Fs
					app.emg_data.time = [1:app.emg_data.pnts] ./ app.emg_data.srate; %#ok<NBRAK> % time in seconds.

					% high pass filter the data (10 Hz)
					app.emg_data.data_hp_filt = hpfilt(app.emg_data.data, 4, 10, app.emg_data.srate);
					% low pass filter the data (400 Hz)
					app.emg_data.data_bp_filt = lpfilt(app.emg_data.data_hp_filt, 4, 400, app.emg_data.srate);
					% rectify & low pass filter at 9 Hz for linear envelope
					app.emg_data.linear_envelope = lpfilt(abs(app.emg_data.data_bp_filt), 4, 9, app.emg_data.srate);

					% check for previous analysis info & read in
					% if prev analysis
					% 						app.AnalysisbyEditField.Value = app.mvcs.(f_names{1}).analysis_by;
					% 						app.AnalysisdateEditField.Value = app.mvcs.(f_names{1}).analysis_date;

					% no prev analysis
					% read in mvc info
					read_mvc_info(app)
					if isempty(app.mvcs)
						warndlg('No MVC files found.', 'MVC Warning', 'modal')
					end

					% compute co-contraction index
					compute_cocontraction(app)

					% draw emg and co-contraction data
					emg_analysis_draw_emg_data(app)

					% add event times using latency sample numbers and srate
					for i = 1:length(app.emg_data.event)
						app.emg_data.event(i).time = app.emg_data.event(i).latency/app.emg_data.srate;
					end

					emg_analysis_draw_events(app) % event lines
				end

			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.CoConUIFigure = uifigure('Visible', 'off');
			app.CoConUIFigure.Name = 'Co-contraction Analysis';
			app.UIAxes_bicep = uiaxes(app.CoConUIFigure);
			ylabel(app.UIAxes_bicep, 'Bicep')
			zlabel(app.UIAxes_bicep, 'Z')
			app.UIAxes_tricep = uiaxes(app.CoConUIFigure);
			ylabel(app.UIAxes_tricep, 'Tricep')
			zlabel(app.UIAxes_tricep, 'Z')
			app.UIAxes_cci = uiaxes(app.CoConUIFigure);
			xlabel(app.UIAxes_cci, 'Time (s)')
			ylabel(app.UIAxes_cci, 'Co-contraction Index')
			zlabel(app.UIAxes_cci, 'Z')
			app.SelectButton = uibutton(app.CoConUIFigure, 'push');
			app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
			app.SelectButton.Text = 'Select';
			app.FileNameLabel = uilabel(app.CoConUIFigure);
			app.EditField = uieditfield(app.CoConUIFigure, 'numeric');
			app.EditField_2 = uieditfield(app.CoConUIFigure, 'numeric');
			app.DataFileLabel = uilabel(app.CoConUIFigure);
			app.DataFileLabel.Text = 'Data File:';
			app.cocontractionmlappversion0120220928Label = uilabel(app.CoConUIFigure);
			app.cocontractionmlappversion0120220928Label.Text = 'cocontraction.mlapp version 0.1 2022-09-28';
			app.AnalysisbyEditFieldLabel = uilabel(app.CoConUIFigure);
			app.AnalysisbyEditFieldLabel.Text = 'Analysis by:';
			app.AnalysisbyEditField = uieditfield(app.CoConUIFigure, 'text');
			app.AnalysisbyEditField.Value = 'XXX';
			app.AnalysisdateEditFieldLabel = uilabel(app.CoConUIFigure);
			app.AnalysisdateEditFieldLabel.Text = 'Analysis date:';
			app.AnalysisdateEditField = uieditfield(app.CoConUIFigure, 'text');
			app.AnalysisdateEditField.Editable = 'off';
			% Show the figure after all components are created
			app.CoConUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = cocontraction

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.CoConUIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CoConUIFigure)
		end
	end
end