classdef mvc < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure       matlab.ui.Figure
		FileNameLabel  matlab.ui.control.Label
		MVCfileLabel   matlab.ui.control.Label
		SelectButton   matlab.ui.control.Button
		UIAxes_tricep  matlab.ui.control.UIAxes
		UIAxes_bicep   matlab.ui.control.UIAxes
	end

	
	properties (Access = private)
		
	end
	
	properties (Access = public)
		emg_data	 % emg data read in from BrainVision file
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: SelectButton
		function SelectButtonPushed(app, event)
			[filename, pathname] = uigetfile('*.vhdr', 'Choose vhdr file with MVC data');
			if ~isequal(filename,0) &&  ~isequal(pathname,0)
				app.FileNameLabel.Text = fullfile(pathname, filename);
				% read in the data
				if contains(filename, '.vhdr')
					% read in data from brain vision file
					app.emg_data = pop_loadbv(pathname, filename, [], [64 65]);
					% data structure:
					%   comments: 'Original file: 20120517_inv_wrist_flexors_active_rc_epochs_450ms.seg' (or .eeg)
					%       srate: 5000				sampling rate in Hz
					%      nbchan: 8				number of channels recorded
					%    chanlocs: [1x8 struct]		array of the channel names (strings)
					%        pnts: 2250				number of data points in a trial
					%        data: [8x90000 single]	data matrix (1 row for each data collection channel)
					%      trials: 40				number of trials
					%        xmin: -0.0500
					%        xmax: 17.9998
					%       event: [1x80 struct]	struct array with fields:
					%									latency			starting data point number for the event
					%									type			'R128' events are the start of trials we want
					%									code
					%									duration
					%									urevent
					%									epoch
					%     urevent: [1x120 struct]
					%         ref: 'common'

					% verify that the 2 data channels are bicep and tricep
					assert(contains(app.emg_data.chanlocs(1).labels, 'bicep'), 'bicep channel not read in')
					assert(contains(app.emg_data.chanlocs(2).labels, 'tricep'), 'tricep channel not read in')
					% add event times using latency sample numbers and srate
					for i = 1:length(app.emg_data.event)
						app.emg_data.event(i).time = app.emg_data.event(i).latency/app.emg_data.srate;
					end
					% high pass filter the data
					app.emg_data.data_hp_filt = hpfilt(app.emg_data.data, 4, 10, app.emg_data.srate);
					emg_analysis_draw_emg_data(app)
					emg_analysis_draw_mvc_patches(app) % or patches for mvc time duration?
				end
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIAxes_bicep = uiaxes(app.UIFigure);
			ylabel(app.UIAxes_bicep, 'Bicep')
			zlabel(app.UIAxes_bicep, 'Z')
			app.UIAxes_tricep = uiaxes(app.UIFigure);
			xlabel(app.UIAxes_tricep, 'Time (s)')
			ylabel(app.UIAxes_tricep, 'Tricep')
			zlabel(app.UIAxes_tricep, 'Z')
			app.SelectButton = uibutton(app.UIFigure, 'push');
			app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
			app.SelectButton.Text = 'Select';
			app.MVCfileLabel = uilabel(app.UIFigure);
			app.MVCfileLabel.Text = 'MVC file:';
			app.FileNameLabel = uilabel(app.UIFigure);
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = mvc

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.UIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.UIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end